classdef mEMbrain_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mEMbrainUIFigure               matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NeurontoBorderTab              matlab.ui.container.Tab
        ConvertNeuronGroundTruthDatatoBorderGroundTruthDataLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        NeuronGroundTruthLabel         matlab.ui.control.Label
        SelectInputDirectoryButton     matlab.ui.control.Button
        userSelectedInputDirectoryLabel  matlab.ui.control.Label
        BorderGroundTruthLabel         matlab.ui.control.Label
        SelectOutputDirectoryButton    matlab.ui.control.Button
        BorderPropertiesLabel          matlab.ui.control.Label
        SetborderpixelstoButtonGroup   matlab.ui.container.ButtonGroup
        Button                         matlab.ui.control.ToggleButton
        Button_2                       matlab.ui.control.ToggleButton
        SetborderthicknesstoSpinnerLabel  matlab.ui.control.Label
        SetborderthicknesstoSpinner    matlab.ui.control.Spinner
        BorderGroundTruthPreviewLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SelectedDirectoryLabel         matlab.ui.control.Label
        userSelectedOutputDirectoryLabel  matlab.ui.control.Label
        SelectedDirectoryLabel_2       matlab.ui.control.Label
        NewProjectTab                  matlab.ui.container.Tab
        FirstTabButton                 matlab.ui.control.StateButton
        SwitchLabel                    matlab.ui.control.Label
        Switch                         matlab.ui.control.RockerSwitch
        KnobLabel                      matlab.ui.control.Label
        Knob                           matlab.ui.control.Knob
        PickUpaProjectTab              matlab.ui.container.Tab
        SecondTabButton                matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectInputDirectoryButton
        function SelectInputDirectoryButtonPushed(app, event)
            selectInputDirectory = uigetdir;
            if selectInputDirectory ~= 0
                app.userSelectedInputDirectoryLabel.Text = selectInputDirectory;
            end
        end

        % Button pushed function: SelectOutputDirectoryButton
        function SelectOutputDirectoryButtonPushed(app, event)
            selectOutputDirectory = uigetdir;
            if selectOutputDirectory ~= 0
                app.userSelectedOutputDirectoryLabel.Text = selectOutputDirectory;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mEMbrainUIFigure = uifigure('Visible', 'off');
            app.mEMbrainUIFigure.Name = 'mEMbrain';
            app.TabGroup = uitabgroup(app.mEMbrainUIFigure);
            app.NeurontoBorderTab = uitab(app.TabGroup);
            app.NeurontoBorderTab.Title = 'Neuron to Border';
            app.ConvertNeuronGroundTruthDatatoBorderGroundTruthDataLabel = uilabel(app.NeurontoBorderTab);
            app.ConvertNeuronGroundTruthDatatoBorderGroundTruthDataLabel.Text = 'Convert Neuron Ground Truth Data to Border Ground Truth Data';
            app.Label = uilabel(app.NeurontoBorderTab);
            app.Label.Text = 'Please select the ground truth directory to convert the neuron data to border data';
            app.NeuronGroundTruthLabel = uilabel(app.NeurontoBorderTab);
            app.NeuronGroundTruthLabel.Text = 'Neuron Ground Truth';
            app.SelectInputDirectoryButton = uibutton(app.NeurontoBorderTab, 'push');
            app.SelectInputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputDirectoryButtonPushed, true);
            app.SelectInputDirectoryButton.Text = 'Select Input Directory...';
            app.userSelectedInputDirectoryLabel = uilabel(app.NeurontoBorderTab);
            app.userSelectedInputDirectoryLabel.Text = 'No Directory Selected';
            app.BorderGroundTruthLabel = uilabel(app.NeurontoBorderTab);
            app.BorderGroundTruthLabel.Text = 'Border Ground Truth';
            app.SelectOutputDirectoryButton = uibutton(app.NeurontoBorderTab, 'push');
            app.SelectOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirectoryButtonPushed, true);
            app.SelectOutputDirectoryButton.Text = 'Select Output Directory...';
            app.BorderPropertiesLabel = uilabel(app.NeurontoBorderTab);
            app.BorderPropertiesLabel.Text = 'Border Properties';
            app.SetborderpixelstoButtonGroup = uibuttongroup(app.NeurontoBorderTab);
            app.SetborderpixelstoButtonGroup.Title = 'Set border pixels to...';
            app.Button = uitogglebutton(app.SetborderpixelstoButtonGroup);
            app.Button.Text = '0';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.SetborderpixelstoButtonGroup);
            app.Button_2.Text = '1';
            app.SetborderthicknesstoSpinnerLabel = uilabel(app.NeurontoBorderTab);
            app.SetborderthicknesstoSpinnerLabel.Text = 'Set border thickness to...';
            app.SetborderthicknesstoSpinner = uispinner(app.NeurontoBorderTab);
            app.SetborderthicknesstoSpinner.Limits = [1 Inf];
            app.SetborderthicknesstoSpinner.Value = 3;
            app.BorderGroundTruthPreviewLabel = uilabel(app.NeurontoBorderTab);
            app.BorderGroundTruthPreviewLabel.Text = 'Border Ground Truth Preview';
            app.Image = uiimage(app.NeurontoBorderTab);
            app.SelectedDirectoryLabel = uilabel(app.NeurontoBorderTab);
            app.SelectedDirectoryLabel.Text = 'Selected Directory: ';
            app.userSelectedOutputDirectoryLabel = uilabel(app.NeurontoBorderTab);
            app.userSelectedOutputDirectoryLabel.Text = 'No Directory Selected';
            app.SelectedDirectoryLabel_2 = uilabel(app.NeurontoBorderTab);
            app.SelectedDirectoryLabel_2.Text = 'Selected Directory: ';
            app.NewProjectTab = uitab(app.TabGroup);
            app.NewProjectTab.Title = 'New Project';
            app.FirstTabButton = uibutton(app.NewProjectTab, 'state');
            app.FirstTabButton.Text = 'First Tab';
            app.SwitchLabel = uilabel(app.NewProjectTab);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.NewProjectTab, 'rocker');
            app.KnobLabel = uilabel(app.NewProjectTab);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.NewProjectTab, 'continuous');
            app.PickUpaProjectTab = uitab(app.TabGroup);
            app.PickUpaProjectTab.Title = 'Pick Up a Project';
            app.SecondTabButton = uibutton(app.PickUpaProjectTab, 'state');
            app.SecondTabButton.Text = 'Second Tab';
            % Show the figure after all components are created
            app.mEMbrainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mEMbrain_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mEMbrainUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mEMbrainUIFigure)
        end
    end
end